classdef hyperviewer2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        DirectoryLabel                matlab.ui.control.Label
        DirectoryListBox              matlab.ui.control.ListBox
        ControlPanel                  matlab.ui.container.Panel
        UnmixAllButton                matlab.ui.control.Button
        SelectUnmixingAlgorithmDropDownLabel  matlab.ui.control.Label
        SelectUnmixingAlgorithmDropDown  matlab.ui.control.DropDown
        SelectDisplayImageDropDownLabel  matlab.ui.control.Label
        SelectDisplayImageDropDown    matlab.ui.control.DropDown
        ResetImageButton              matlab.ui.control.Button
        EnableParpoolCheckBox         matlab.ui.control.CheckBox
        UnmixingTimeEditFieldLabel    matlab.ui.control.Label
        UnmixingTimeEditField         matlab.ui.control.NumericEditField
        UnmixingTimeUnitLabel         matlab.ui.control.Label
        BrightnessSliderLabel         matlab.ui.control.Label
        BrightnessSlider              matlab.ui.control.Slider
        CenterPanel                   matlab.ui.container.Panel
        HyperViewer2Label             matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        msgbox                        matlab.ui.control.Label
        UIAxes1                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        BSLTable                      matlab.ui.control.Table
        BSLLabel                      matlab.ui.control.Label
        UnmixButton                   matlab.ui.control.Button
        SaveBSLButton                 matlab.ui.control.Button
        ChooseAnalysisDropDownLabel   matlab.ui.control.Label
        ChooseAnalysisDropDown        matlab.ui.control.DropDown
        NEditFieldLabel               matlab.ui.control.Label
        NEditField                    matlab.ui.control.NumericEditField
        UIAxes3                       matlab.ui.control.UIAxes
        File                          matlab.ui.container.Menu
        NewMenu                       matlab.ui.container.Menu
        hyperCFMEMenu                 matlab.ui.container.Menu
        MaestroMenu                   matlab.ui.container.Menu
        BioFormatsMenu                matlab.ui.container.Menu
        DavisLabMenu                  matlab.ui.container.Menu
        ENVIMenu                      matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        ReloadMenu                    matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        AnalysisMenu                  matlab.ui.container.Menu
        AllAnalysesMenu               matlab.ui.container.Menu
        AsTiffStacksMenu              matlab.ui.container.Menu
        NewImageMenu                  matlab.ui.container.Menu
        ExportAsMenu                  matlab.ui.container.Menu
        MovieMenu                     matlab.ui.container.Menu
        NewBasisSpectraMenu           matlab.ui.container.Menu
        TilesMenu                     matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        SpectraViewerMenu             matlab.ui.container.Menu
        SpeedtestCPUMenu              matlab.ui.container.Menu
        SpeedtestGPUMenu              matlab.ui.container.Menu
        HistogramAnalysisMenu         matlab.ui.container.Menu
        ROIMenu                       matlab.ui.container.Menu
        NewROIMenu                    matlab.ui.container.Menu
        AddROIMenu                    matlab.ui.container.Menu
        NewPointMenu                  matlab.ui.container.Menu
        AddPointMenu                  matlab.ui.container.Menu
        AddROItoBSLMenu               matlab.ui.container.Menu
        ClearLastROIMenu              matlab.ui.container.Menu
        AverageExptMenu               matlab.ui.container.Menu
        PreunmixingMenu               matlab.ui.container.Menu
        PostunmixingMenu              matlab.ui.container.Menu
        PhasorDenoisingMenu           matlab.ui.container.Menu
        OptionsMenu                   matlab.ui.container.Menu
        IncludeAllSpectrainCompositeMenu  matlab.ui.container.Menu
        IncludeSaturatedPixelsMenu    matlab.ui.container.Menu
        GaussianBlurMenu              matlab.ui.container.Menu
        ApplyFlatfieldCorrectionMenu  matlab.ui.container.Menu
        GlobalROIMenu                 matlab.ui.container.Menu
        ApplyFiberImageFilterMenu     matlab.ui.container.Menu
        SamplingCorrectionMenu        matlab.ui.container.Menu
        SpectralSmoothingMenu         matlab.ui.container.Menu
        DemoMenu                      matlab.ui.container.Menu
        hyperCFMEtestMenu             matlab.ui.container.Menu
        MaestrotestMenu               matlab.ui.container.Menu
        ENVItestapplesMenu            matlab.ui.container.Menu
        ENVItestbloodstainsMenu       matlab.ui.container.Menu
        OlympustestMenu               matlab.ui.container.Menu
        OIRtiffstestMenu              matlab.ui.container.Menu
        DebugcodeMenu                 matlab.ui.container.Menu
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        % Initialize structs for data/variable organization
        % Experiment variables - these are only changed when a new expt is loaded
        expt = struct('dirname',    [], ... [str] Path to top-level directory containing images or subfolders of images
            'filetype',   [], ... [str] Indicates detected image filetype
            'mode',       'normal',  ... [str] Specify operation mode {'normal', 'reload', 'debug'}
            'debugExamp', 'off',     ... [str] filetype of debug example {'off', 'hyper', 'maestro', 'apples', 'blood', 'oir', 'oir-tiff'}
            'bslFile',    'BSL.mat', ... [str] file name of the basis spectra library
            'loaded',     false, ... [logical] indicates expt has been loaded succesfully
            'loadvec',    []);   ... [dbl] vector of loading percentages per
            
        % Configuration variables for analysis
        cfg = struct('num_imgs',     0, ... [dbl] # of images in the expt
            'num_spec',     0, ... [dbl] # of basis spectra for image analysis
            'poolobj',     [], ... [pool] Parpool obj for CPU-unmixing
            'gpuUnmix',    [], ... [logical] idicates if gpu is available for unmixing
            'gpuDevCount', [], ... [dbl] # of usable gpus detected
            'gpuInfo',     [], ... [cell] info about local gpus returned by 'gpuDevice'
            'gpuIdx',      [], ... [dbl] index of the selected gpu configuration
            'algorithm',   [], ... [str] algorithm type {'gnnls', 'fnnls', 'lsqnonneg', 'fourier', 'chebyshev'}
            'ARCHSTR',     [], ... [str] detected computer architecture
            'roiIdx',       1, ... [dbl] index indicating the roi to apply
            'roiColors',   [], ...
            'fiber_img',   '1mmfiberimage.tif', ... [str] path to fiber core image for hyperCFME mask
            'fiber_mask',  [], ... [logical] logical mask of fiber cores
            'sigma',        4, ... [dbl] width of gaussian blurring filter in pixels
            'gaussfilt',   [], ... [dbl] gaussian filter for image blurring
            'phasor',      [], ... [struct] sub-struct for phasor analysis params
            'compress',    []);... [struct] sub-struct for image compression params
            
%         % Keeps track of the gui state parameters used as variables.
        state = struct('img', 1,  ... [dbl] idx of currently selected image (only one at a time)
            'spec',        1,  ... [dbl] idx of currently selected basis spectra in A matrix (may be vector)
            'bsl',         1,  ... [dbl] idx of currently selected spectra in BSL (may be vector)
            'img_list',    [], ... [cell] array of image file names in img listbox
            'spec_list',   [], ... [cell] array of spectra names in matrix listbox
            'bsl_list',    []); ... [cell] array of spectra names in bsl listbox
%             'brightness',  1,  ... [dbl] multiplier to brighten image on display
%             'blur',        true,  ... [logical] apply spatial gaussian blur to image
%             'fiberfilter', false, ... [logical] apply fiber core filter mask to image (hyperCFME only)
%             'flatfield',   false, ... [logical] apply flatfield image correction
%             'allspec',     false, ... [logical] include all spectra in composite (some are excluded by default)
%             'satpix',      false, ... [logical] apply saturated pixel mask to exclude from analysis
%             'globalroi',   false, ... [logical] use one roi on all other images
%             'compress',    false, ... [logical] compress over-sampled images (hyperCFME only)
%             'smoothSpec',  false, ... [logical] apply smoothing-spline spectral denoising (slow!! nor reccomended)
%             'errorbars',   false,  ... [logical] show errorbars
%             'legend',      false); ... [logical] show legend
            
          img = image;
%         % Initialize image structure
%         img = struct('info',     [], ... [struct] directory info returend by dir
%             'path',          [], ... [str] path to image or image directory
%             'label',         [], ... [str] name of image or image directory (displayed above image listbox)
%             'isLoaded',      [], ... [logical] indicates if image has been loaded
%             'isUnmixed',     [], ... [logical] indicates if image has been analyzed
%             'power',         [], ... [dbl] laser power (if applicable)
%             'filename',      [], ... [str] image filename, no ext. (set as the string in the image listbox)
%             'imagefiles',    [], ... [cell] full image filename(s) with extension
%             'cube',          [], ... [dbl] raw hyperspectral data cube
%             'disp',          [], ... [dbl] hyperspectral data cube after pre processing
%             'imx',           [], ... [dbl] image size in x dir
%             'imy',           [], ... [dbl] image size in y dir
%             'satpix',        [], ... [logical] saturated pixel mask
%             'A',             [], ... [dbl] unmixing matrix
%             'x',             [], ... [dbl] unmixed basis image cube
%             'x_corr',        [], ... [dbl] unmixed basis image cube with flatfield correction
%             'ff_correction', [], ... [dbl] flatfield correction matrix
%             'redchisq',      [], ... [dbl] map of reduced chi squared error in unmixing
%             'evalTime',      [], ... [dbl] unmixing computation time
%             'colorims',      [], ... [struct] pseudocolored composite images for display
%             'roi',           [], ... [struct] roi mask and info
%             'roiStats',      [], ... [struct] statistics of the image defined by the roi
%             'wl',            [], ... [dbl] vector of wavelength that define cube (can be different from basis spectra)
%             'phasor',        [], ... [struct] phasor analysis variables
%             'filt',          []);... [logical] vector used to filter wavlength channels if needed
%             
        % Global GUI Messages
        msg = struct('nogpu',   'No compatible GPU device was found on this machine, NNLS decomposition analysis will be done on the CPU and might take a while.', ...
            'gpuEnabled',      'GPU decomposition enabled', ...
            'cpu_withparpool', 'CPU decomposition enabled with parallel pool cluster.', ...
            'cpu_noparpool',   'Sequential CPU decomposition enabled.' , ...
            'loadingexpt',     'Loading Experiment... Please Wait.', ...
            'loadingcube',     'Loading Image Cube... Please Wait.', ...
            'unmixing',        'Image analysis in progress, please wait.', ...
            'imageNotAnalzed', 'Error: This image has not been analyzed.', ...
            'loadexpt',        'Please load an expiriment first.', ...
            'loadBasis',       'Please add a basis spectra to the A matrix to analyze image.', ...
            'unmixall',        'Unmix all images first.');
        
        
    end
    
    methods (Access = private)
        
        function out = toggle(~, in)
            % input a binary variable and return its opposite value
            c = class(in);
            
            switch c
                case 'logical'
                    if in
                        out = false;
                    else
                        out = true;
                    end
                    
                case 'char'
                    if strcmp(in, 'on')
                        out = 'off';
                    elseif strcmp(in, 'off')
                        out = 'on';
                    end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add library to MATLAB Path.
            addpath(genpath([cd filesep 'lib']));
            
            % Check for demo folder
            if isempty(genpath([cd filesep 'demo']))
                warning(sprintf(['Demo folder not detected, demos will not work. \n\n' ...
                    'Download the demo folder from www.springlabnu.com and copy to' ...
                    'Hyperviewer working directory'])); %#ok<SPWRN>
            else
                addpath(genpath([cd filesep 'demo']));
            end
            
            % Set default msgbox string:
            app.msgbox.String = 'Click Load Experiment to begin.';
            
            
            %% Processor settings
            
            % Check computer OS
            app.cfg.ARCHSTR = computer('arch');
            
            % Universal algorithm configurations (don't need gpu)
            algstr{1} = 'CPU - FNNLS';
            algstr{2} = 'CPU - LSQNONNEG';
            algstr{3} = 'CPU - Fourier Phasor';
            algstr{4} = 'CPU - Chebyshev Phasor';
            
            numDefAlg = length(algstr);
            
            % Look for compatible GPUs on machine
            app.cfg.gpuDevCount = gpuDeviceCount;
            % handles.gpuDevCount = 0;
            
            % Check for parpool. If no pool, do not create new one.
            app.cfg.poolobj = gcp('nocreate');
            
            if app.cfg.gpuDevCount == 0 % No gpu was found
                
                % Disable GPU unmixing
                app.cfg.gpuUnmix = false;
                app.cfg.algorithm = 'fnnls';
                
                if isempty(app.cfg.poolobj)
                    
                    % Query user to start a parpool (NNLSCPU is parfor enabled)
                    response = questdlg([app.msg.nogpu 'Would you like to enable ' ...
                        'the Parallel Computation Toolbox to speed up the '     ...
                        'analysis?'], 'WARNING: No GPU Device Found.', ...
                        'Yes', 'No', 'Yes');
                    
                    switch response
                        case 'Yes'
                            % Enable parpool
                            app.cfg.poolobj = parpool('local');
                            
                            
                            % Infom user about parpool
                            handles.msgbox.String = app.msg.cpu_withparpool;
                            
                        case 'No'
                            % Do nothing
                            % Inform user about algorithm status.
                            handles.msgbox.String = app.msg.cpu_noparpool;
                    end
                    
                end
                
                
            else  % At least 1 GPU was found. TODO: Add option to choose number of GPU
                
                % Enable GPU unmixing.
                app.cfg.gpuUnmix = true;
                app.cfg.algorithm = 'gnnls';
                handles.msgbox.String = app.msg.gpuEnabled;
                
                % Get gpu device names
                for i = 1:app.cfg.gpuDevCount
                    app.cfg.gpuInfo{i} = gpuDevice(i);
                    
                    % Add to list of algortim cfgs (4 defaults exist from above)
                    algstr{i + numDefAlg} = ['GPU - ' app.cfg.gpuInfo{i}.Name];
                end
                
                app.cfg.gpuIdx = [zeros(1, app.cfg.gpuDevCount) 0];
            end
            
            % Set string values
            handles.cpugpu_popupmenu.String = algstr;
            
            % Defalut algorithm cfg
            if app.cfg.gpuUnmix
                % Start with first gpu device
                gpuDevice(1);
                app.cfg.gpuIdx(1) = 1;
                handles.cpugpu_popupmenu.Value = numDefAlg + 1;
            else
                handles.cpugpu_popupmenu.Value = 1;
            end
            
            
            %%  Load Basis Spectra File
            load(app.expt.bslFile);
            
            % Set BSL and load spec names, colors, labels
            app.lib = loadBSL(BSL, 'all', false);
            
            % Update BSL listbox
            app.state.bsl_list = {lib.tags}';
            
            % Default A matrix is empty
            [lib.matidx] = deal(false);
            
            % Load spectra
            [spec, app.lib] = loadSpectra(app);
            
            % Update A_mat listbox
            app.state.mat_list = {spec.tags}';
            
            %% Default parameters
            
            % Analysis params
            % How many pixels is the image over sampled?
            app.cfg.compress.x = 1;
            app.cfg.compress.y = 6;
            
            % Default harmonics for phasor plots
            app.cfg.phasor.f = 1;
            app.cfg.phasor.c = 2;
            
            % Gaussian mean filter to remove fiber cores
            app.cfg.gaussfilt = fspecial('gaussian', [6 6], app.cfg.sigma);
            
            app.cfg.roiColors = {[0.8 0.8 0.8], 'Green', 'Cyan', 'Yellow', 'Magenta', 'Red'};
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {726, 726, 726};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {726, 726};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {279, '1x', 426};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: UnmixAllButton
        function UnmixAllButtonPushed(app, event)
            
            if ~app.expt.loaded
                app.msgbox.String = app.msg.loadexpt;
                return;
            end
            
            handles.msgbox.String = 'Unmixing all images...  0%';
            
            % Switch to axes3 to display unmixing stats
            handles.axes3; hold on;
            title('Unmixing Speed');
            xlabel('Image #');
            ylabel('Time (s)');
            xlim([0 handles.cfg.num_imgs]);
            legend off;
            set(gca, 'YScale', 'log');
            
            
            t_load = zeros(1, handles.cfg.num_imgs);
            lag    = zeros(1, handles.cfg.num_imgs);
            
            % Analyze images images that have not been unmixed.
            for n = 1:handles.cfg.num_imgs
                
                % If image has not been loaded, load it
                if ~handles.img(n).isLoaded
                    
                    % Load image
                    tic
                    [handles.img(n), ME] = getImageCube(handles.img(n), handles.expt.filetype);
                    t_load(n) = toc;
                    
                    % Update filename before checking the error
                    handles.img_listbox.String = {handles.img.filename};
                    
                    if ~isempty(ME)
                        handles.msgbox.String = ME;
                        warning(ME);
                    end
                end
                
                
                % Unmix image (here, do not skip the analysis if already done,
                % this makes sure all images are analyzed with the same parameters)
                if strcmp(handles.img(n).filename, 'NOT_LOADED')
                    continue;
                else
                    [handles.img(n), ME] = unmixImage(handles.img(n), handles);
                end
                if ~isempty(ME)
                    handles.msgbox.String = handles.msg.nogpu;
                    warning(ME);
                end
                
                % Get region stats
                handles.img(n).roiStats = getRegionStats(handles.img(n), handles.img(handles.cfg.roiIdx).roi.mask);
                
                lag(n) = t_load(n) - handles.img(n).evalTime;
                plot(n, t_load(n), 'b*', n, lag(n), 'ro');
                ylim([-inf inf]);
                
                handles.msgbox.String = sprintf('Unmixing all images... %1.0f %%', ...
                    handles.expt.loadvec(n));
                drawnow;
                
            end
            
            handles.msgbox.String = 'Ready.';
            % Obsolete, delete all guidata functions
            %% guidata(hObject, handles);
        end

        % Menu selected function: hyperCFMEMenu
        function hyperCFMEMenuSelected(app, event)
            app.expt.filetype = 'hyper';
            loadExperiment(app);
        end

        % Menu selected function: MaestroMenu
        function MaestroMenuSelected(app, event)
            app.expt.filetype = 'maestro';
            loadExperiment(app);
        end

        % Menu selected function: BioFormatsMenu
        function BioFormatsMenuSelected(app, event)
            app.expt.filetype = 'bioformat';
            loadExperiment(app);
        end

        % Menu selected function: DavisLabMenu
        function DavisLabMenuSelected(app, event)
            app.expt.filetype = 'macrotome';
            loadExperiment(app);
        end

        % Menu selected function: ENVIMenu
        function ENVIMenuSelected(app, event)
            app.expt.filetype = 'envi';
            loadExperiment(app);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            x = 3;
        end

        % Menu selected function: ReloadMenu
        function ReloadMenuSelected(app, event)
            
        end

        % Menu selected function: AnalysisMenu
        function AnalysisMenuSelected(app, event)
            
        end

        % Menu selected function: AllAnalysesMenu
        function AllAnalysesMenuSelected(app, event)
            
        end

        % Menu selected function: AsTiffStacksMenu
        function AsTiffStacksMenuSelected(app, event)
            
        end

        % Menu selected function: NewImageMenu
        function NewImageMenuSelected(app, event)
            
        end

        % Menu selected function: MovieMenu
        function MovieMenuSelected(app, event)
            
        end

        % Menu selected function: NewBasisSpectraMenu
        function NewBasisSpectraMenuSelected(app, event)
            
        end

        % Menu selected function: TilesMenu
        function TilesMenuSelected(app, event)
            
        end

        % Menu selected function: NewROIMenu
        function NewROIMenuSelected(app, event)
            
        end

        % Menu selected function: AddROIMenu
        function AddROIMenuSelected(app, event)
            
        end

        % Menu selected function: NewPointMenu
        function NewPointMenuSelected(app, event)
            
        end

        % Menu selected function: AddPointMenu
        function AddPointMenuSelected(app, event)
            
        end

        % Menu selected function: AddROItoBSLMenu
        function AddROItoBSLMenuSelected(app, event)
            
        end

        % Menu selected function: ClearLastROIMenu
        function ClearLastROIMenuSelected(app, event)
            
        end

        % Menu selected function: PreunmixingMenu
        function PreunmixingMenuSelected(app, event)
            
        end

        % Menu selected function: PostunmixingMenu
        function PostunmixingMenuSelected(app, event)
            
        end

        % Menu selected function: SpectraViewerMenu
        function SpectraViewerMenuSelected(app, event)
            
        end

        % Menu selected function: SpeedtestCPUMenu
        function SpeedtestCPUMenuSelected(app, event)
            
        end

        % Menu selected function: SpeedtestGPUMenu
        function SpeedtestGPUMenuSelected(app, event)
            
        end

        % Menu selected function: HistogramAnalysisMenu
        function HistogramAnalysisMenuSelected(app, event)
            
        end

        % Menu selected function: PhasorDenoisingMenu
        function PhasorDenoisingMenuSelected(app, event)
            
        end

        % Menu selected function: IncludeAllSpectrainCompositeMenu
        function IncludeAllSpectrainCompositeMenuSelected(app, event)
            
        end

        % Menu selected function: IncludeSaturatedPixelsMenu
        function IncludeSaturatedPixelsMenuSelected(app, event)
            
        end

        % Menu selected function: GaussianBlurMenu
        function GaussianBlurMenuSelected(app, event)
            
        end

        % Menu selected function: ApplyFlatfieldCorrectionMenu
        function ApplyFlatfieldCorrectionMenuSelected(app, event)
            
        end

        % Menu selected function: GlobalROIMenu
        function GlobalROIMenuSelected(app, event)
            
        end

        % Menu selected function: ApplyFiberImageFilterMenu
        function ApplyFiberImageFilterMenuSelected(app, event)
            
        end

        % Menu selected function: SamplingCorrectionMenu
        function SamplingCorrectionMenuSelected(app, event)
            
        end

        % Menu selected function: SpectralSmoothingMenu
        function SpectralSmoothingMenuSelected(app, event)
            
        end

        % Menu selected function: hyperCFMEtestMenu
        function hyperCFMEtestMenuSelected(app, event)
            
        end

        % Menu selected function: MaestrotestMenu
        function MaestrotestMenuSelected(app, event)
            
        end

        % Menu selected function: ENVItestapplesMenu
        function ENVItestapplesMenuSelected(app, event)
            
        end

        % Menu selected function: ENVItestbloodstainsMenu
        function ENVItestbloodstainsMenuSelected(app, event)
            
        end

        % Menu selected function: OlympustestMenu
        function OlympustestMenuSelected(app, event)
            
        end

        % Menu selected function: OIRtiffstestMenu
        function OIRtiffstestMenuSelected(app, event)
            
        end

        % Menu selected function: DebugcodeMenu
        function DebugcodeMenuSelected(app, event)
            test = 1; %#ok<NASGU> 
        end

        % Cell edit callback: BSLTable
        function BSLTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            % go and update the BSL
            
            
        end

        % Cell selection callback: BSLTable
        function BSLTableCellSelection(app, event)
            indices = event.Indices;
            
        end

        % Button pushed function: UnmixButton
        function UnmixButtonPushed(app, event)
            
        end

        % Button pushed function: SaveBSLButton
        function SaveBSLButtonPushed(app, event)
            
        end

        % Value changed function: SelectUnmixingAlgorithmDropDown
        function SelectUnmixingAlgorithmDropDownValueChanged(app, event)
            value = app.SelectUnmixingAlgorithmDropDown.Value;
            
            switch value
                
            end
        end

        % Value changed function: SelectDisplayImageDropDown
        function SelectDisplayImageDropDownValueChanged(app, event)
            value = app.SelectDisplayImageDropDown.Value;
            
            switch value
                case 'Unmixed Composite'
                    
                case 'Lambda Composite'
                    
                case 'Reduced Chi-squared Map'
                
            end
            
            display(app)
            
        end

        % Button pushed function: ResetImageButton
        function ResetImageButtonPushed(app, event)
            
            % TODO Reset roi's on image
            
            display(app)
        end

        % Value changed function: EnableParpoolCheckBox
        function EnableParpoolCheckBoxValueChanged(app, event)
            value = app.EnableParpoolCheckBox.Value;
            
            % TODO toggle parpool based on user selection
            
            display(app)
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            value = app.BrightnessSlider.Value;
            
            display(app)
        end

        % Value changed function: ChooseAnalysisDropDown
        function ChooseAnalysisDropDownValueChanged(app, event)
            value = app.ChooseAnalysisDropDown.Value;
            
            
            display(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DirectoryLabel = uilabel(app.LeftPanel);
            app.DirectoryLabel.Text = 'Directory';
            app.DirectoryListBox = uilistbox(app.LeftPanel);
            app.DirectoryListBox.Items = {};
            app.DirectoryListBox.Value = {};
            app.ControlPanel = uipanel(app.LeftPanel);
            app.ControlPanel.Title = 'Control Panel';
            app.UnmixAllButton = uibutton(app.ControlPanel, 'push');
            app.UnmixAllButton.ButtonPushedFcn = createCallbackFcn(app, @UnmixAllButtonPushed, true);
            app.UnmixAllButton.Text = 'Unmix All';
            app.SelectUnmixingAlgorithmDropDownLabel = uilabel(app.ControlPanel);
            app.SelectUnmixingAlgorithmDropDownLabel.Text = 'Select Unmixing Algorithm';
            app.SelectUnmixingAlgorithmDropDown = uidropdown(app.ControlPanel);
            app.SelectUnmixingAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectUnmixingAlgorithmDropDownValueChanged, true);
            app.SelectDisplayImageDropDownLabel = uilabel(app.ControlPanel);
            app.SelectDisplayImageDropDownLabel.Text = 'Select Display Image';
            app.SelectDisplayImageDropDown = uidropdown(app.ControlPanel);
            app.SelectDisplayImageDropDown.Items = {'Unmixed Composite', 'Lambda Composite', 'Reduced Chi-squared Map'};
            app.SelectDisplayImageDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDisplayImageDropDownValueChanged, true);
            app.SelectDisplayImageDropDown.Value = 'Unmixed Composite';
            app.ResetImageButton = uibutton(app.ControlPanel, 'push');
            app.ResetImageButton.ButtonPushedFcn = createCallbackFcn(app, @ResetImageButtonPushed, true);
            app.ResetImageButton.Text = 'Reset Image';
            app.EnableParpoolCheckBox = uicheckbox(app.ControlPanel);
            app.EnableParpoolCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableParpoolCheckBoxValueChanged, true);
            app.EnableParpoolCheckBox.Text = 'Enable Parpool';
            app.UnmixingTimeEditFieldLabel = uilabel(app.ControlPanel);
            app.UnmixingTimeEditFieldLabel.Text = 'Unmixing Time';
            app.UnmixingTimeEditField = uieditfield(app.ControlPanel, 'numeric');
            app.UnmixingTimeEditField.Limits = [0 Inf];
            app.UnmixingTimeEditField.ValueDisplayFormat = '%5.2f';
            app.UnmixingTimeEditField.Editable = 'off';
            app.UnmixingTimeUnitLabel = uilabel(app.ControlPanel);
            app.UnmixingTimeUnitLabel.Text = 's';
            app.BrightnessSliderLabel = uilabel(app.ControlPanel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.ControlPanel);
            app.BrightnessSlider.Limits = [0 10];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.HyperViewer2Label = uilabel(app.CenterPanel);
            app.HyperViewer2Label.Text = 'HyperViewer2';
            app.StatusLabel = uilabel(app.CenterPanel);
            app.StatusLabel.Text = 'Status: ';
            app.msgbox = uilabel(app.CenterPanel);
            app.msgbox.Text = '';
            app.UIAxes1 = uiaxes(app.CenterPanel);
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.UIAxes2 = uiaxes(app.CenterPanel);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.BSLTable = uitable(app.RightPanel);
            app.BSLTable.ColumnName = {'A matrix?'; 'Dye'; 'Target'; 'Color'};
            app.BSLTable.RowName = {};
            app.BSLTable.CellEditCallback = createCallbackFcn(app, @BSLTableCellEdit, true);
            app.BSLTable.CellSelectionCallback = createCallbackFcn(app, @BSLTableCellSelection, true);
            app.BSLLabel = uilabel(app.RightPanel);
            app.BSLLabel.Text = 'BSL';
            app.UnmixButton = uibutton(app.RightPanel, 'push');
            app.UnmixButton.ButtonPushedFcn = createCallbackFcn(app, @UnmixButtonPushed, true);
            app.UnmixButton.Text = 'Unmix';
            app.SaveBSLButton = uibutton(app.RightPanel, 'push');
            app.SaveBSLButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBSLButtonPushed, true);
            app.SaveBSLButton.Text = 'Save BSL';
            app.ChooseAnalysisDropDownLabel = uilabel(app.RightPanel);
            app.ChooseAnalysisDropDownLabel.Text = 'Choose Analysis:';
            app.ChooseAnalysisDropDown = uidropdown(app.RightPanel);
            app.ChooseAnalysisDropDown.Items = {'Spectral Fit', 'Histogram', 'Fourier Phasor Plot', 'Chebyshev Phasor Plot'};
            app.ChooseAnalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseAnalysisDropDownValueChanged, true);
            app.ChooseAnalysisDropDown.Value = 'Spectral Fit';
            app.NEditFieldLabel = uilabel(app.RightPanel);
            app.NEditFieldLabel.Enable = 'off';
            app.NEditFieldLabel.Text = 'N =';
            app.NEditField = uieditfield(app.RightPanel, 'numeric');
            app.NEditField.Enable = 'off';
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Analysis')
            xlabel(app.UIAxes3, 'Wavelength (nm)')
            app.UIAxes3.XLim = [0 1];
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.NewMenu = uimenu(app.File);
            app.NewMenu.Accelerator = 'N';
            app.NewMenu.Text = 'New';
            app.hyperCFMEMenu = uimenu(app.NewMenu);
            app.hyperCFMEMenu.MenuSelectedFcn = createCallbackFcn(app, @hyperCFMEMenuSelected, true);
            app.hyperCFMEMenu.Text = 'hyperCFME';
            app.MaestroMenu = uimenu(app.NewMenu);
            app.MaestroMenu.MenuSelectedFcn = createCallbackFcn(app, @MaestroMenuSelected, true);
            app.MaestroMenu.Text = 'Maestro';
            app.BioFormatsMenu = uimenu(app.NewMenu);
            app.BioFormatsMenu.MenuSelectedFcn = createCallbackFcn(app, @BioFormatsMenuSelected, true);
            app.BioFormatsMenu.Text = 'BioFormats';
            app.DavisLabMenu = uimenu(app.NewMenu);
            app.DavisLabMenu.MenuSelectedFcn = createCallbackFcn(app, @DavisLabMenuSelected, true);
            app.DavisLabMenu.Text = 'DavisLab';
            app.ENVIMenu = uimenu(app.NewMenu);
            app.ENVIMenu.MenuSelectedFcn = createCallbackFcn(app, @ENVIMenuSelected, true);
            app.ENVIMenu.Text = 'ENVI';
            app.OpenMenu = uimenu(app.File);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ReloadMenu = uimenu(app.File);
            app.ReloadMenu.MenuSelectedFcn = createCallbackFcn(app, @ReloadMenuSelected, true);
            app.ReloadMenu.Text = 'Reload';
            app.SaveMenu = uimenu(app.File);
            app.SaveMenu.Text = 'Save';
            app.AnalysisMenu = uimenu(app.SaveMenu);
            app.AnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @AnalysisMenuSelected, true);
            app.AnalysisMenu.Text = 'Analysis';
            app.AllAnalysesMenu = uimenu(app.SaveMenu);
            app.AllAnalysesMenu.MenuSelectedFcn = createCallbackFcn(app, @AllAnalysesMenuSelected, true);
            app.AllAnalysesMenu.Text = 'All Analyses';
            app.AsTiffStacksMenu = uimenu(app.SaveMenu);
            app.AsTiffStacksMenu.MenuSelectedFcn = createCallbackFcn(app, @AsTiffStacksMenuSelected, true);
            app.AsTiffStacksMenu.Text = 'As Tiff Stacks';
            app.NewImageMenu = uimenu(app.SaveMenu);
            app.NewImageMenu.MenuSelectedFcn = createCallbackFcn(app, @NewImageMenuSelected, true);
            app.NewImageMenu.Text = 'New Image';
            app.ExportAsMenu = uimenu(app.File);
            app.ExportAsMenu.Text = 'Export As';
            app.MovieMenu = uimenu(app.ExportAsMenu);
            app.MovieMenu.MenuSelectedFcn = createCallbackFcn(app, @MovieMenuSelected, true);
            app.MovieMenu.Text = 'Movie';
            app.NewBasisSpectraMenu = uimenu(app.ExportAsMenu);
            app.NewBasisSpectraMenu.MenuSelectedFcn = createCallbackFcn(app, @NewBasisSpectraMenuSelected, true);
            app.NewBasisSpectraMenu.Text = 'New Basis Spectra';
            app.TilesMenu = uimenu(app.ExportAsMenu);
            app.TilesMenu.MenuSelectedFcn = createCallbackFcn(app, @TilesMenuSelected, true);
            app.TilesMenu.Text = 'Tiles';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.SpectraViewerMenu = uimenu(app.ToolsMenu);
            app.SpectraViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @SpectraViewerMenuSelected, true);
            app.SpectraViewerMenu.Text = 'SpectraViewer';
            app.SpeedtestCPUMenu = uimenu(app.ToolsMenu);
            app.SpeedtestCPUMenu.MenuSelectedFcn = createCallbackFcn(app, @SpeedtestCPUMenuSelected, true);
            app.SpeedtestCPUMenu.Text = 'Speedtest CPU';
            app.SpeedtestGPUMenu = uimenu(app.ToolsMenu);
            app.SpeedtestGPUMenu.MenuSelectedFcn = createCallbackFcn(app, @SpeedtestGPUMenuSelected, true);
            app.SpeedtestGPUMenu.Text = 'Speedtest GPU';
            app.HistogramAnalysisMenu = uimenu(app.ToolsMenu);
            app.HistogramAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramAnalysisMenuSelected, true);
            app.HistogramAnalysisMenu.Text = 'Histogram Analysis';
            app.ROIMenu = uimenu(app.ToolsMenu);
            app.ROIMenu.Text = 'ROI';
            app.NewROIMenu = uimenu(app.ROIMenu);
            app.NewROIMenu.MenuSelectedFcn = createCallbackFcn(app, @NewROIMenuSelected, true);
            app.NewROIMenu.Text = 'New ROI';
            app.AddROIMenu = uimenu(app.ROIMenu);
            app.AddROIMenu.MenuSelectedFcn = createCallbackFcn(app, @AddROIMenuSelected, true);
            app.AddROIMenu.Text = 'Add ROI';
            app.NewPointMenu = uimenu(app.ROIMenu);
            app.NewPointMenu.MenuSelectedFcn = createCallbackFcn(app, @NewPointMenuSelected, true);
            app.NewPointMenu.Text = 'New Point';
            app.AddPointMenu = uimenu(app.ROIMenu);
            app.AddPointMenu.MenuSelectedFcn = createCallbackFcn(app, @AddPointMenuSelected, true);
            app.AddPointMenu.Text = 'Add Point';
            app.AddROItoBSLMenu = uimenu(app.ROIMenu);
            app.AddROItoBSLMenu.MenuSelectedFcn = createCallbackFcn(app, @AddROItoBSLMenuSelected, true);
            app.AddROItoBSLMenu.Text = 'Add ROI to BSL';
            app.ClearLastROIMenu = uimenu(app.ROIMenu);
            app.ClearLastROIMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearLastROIMenuSelected, true);
            app.ClearLastROIMenu.Text = 'Clear Last ROI';
            app.AverageExptMenu = uimenu(app.ToolsMenu);
            app.AverageExptMenu.Text = 'Average Expt';
            app.PreunmixingMenu = uimenu(app.AverageExptMenu);
            app.PreunmixingMenu.MenuSelectedFcn = createCallbackFcn(app, @PreunmixingMenuSelected, true);
            app.PreunmixingMenu.Text = 'Pre-unmixing';
            app.PostunmixingMenu = uimenu(app.AverageExptMenu);
            app.PostunmixingMenu.MenuSelectedFcn = createCallbackFcn(app, @PostunmixingMenuSelected, true);
            app.PostunmixingMenu.Text = 'Post-unmixing';
            app.PhasorDenoisingMenu = uimenu(app.ToolsMenu);
            app.PhasorDenoisingMenu.MenuSelectedFcn = createCallbackFcn(app, @PhasorDenoisingMenuSelected, true);
            app.PhasorDenoisingMenu.Text = 'Phasor Denoising';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.IncludeAllSpectrainCompositeMenu = uimenu(app.OptionsMenu);
            app.IncludeAllSpectrainCompositeMenu.MenuSelectedFcn = createCallbackFcn(app, @IncludeAllSpectrainCompositeMenuSelected, true);
            app.IncludeAllSpectrainCompositeMenu.Text = 'Include All Spectra in Composite';
            app.IncludeSaturatedPixelsMenu = uimenu(app.OptionsMenu);
            app.IncludeSaturatedPixelsMenu.MenuSelectedFcn = createCallbackFcn(app, @IncludeSaturatedPixelsMenuSelected, true);
            app.IncludeSaturatedPixelsMenu.Text = 'Include Saturated Pixels';
            app.GaussianBlurMenu = uimenu(app.OptionsMenu);
            app.GaussianBlurMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianBlurMenuSelected, true);
            app.GaussianBlurMenu.Text = 'Gaussian Blur';
            app.ApplyFlatfieldCorrectionMenu = uimenu(app.OptionsMenu);
            app.ApplyFlatfieldCorrectionMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplyFlatfieldCorrectionMenuSelected, true);
            app.ApplyFlatfieldCorrectionMenu.Text = 'Apply Flatfield Correction';
            app.GlobalROIMenu = uimenu(app.OptionsMenu);
            app.GlobalROIMenu.MenuSelectedFcn = createCallbackFcn(app, @GlobalROIMenuSelected, true);
            app.GlobalROIMenu.Text = 'Global ROI';
            app.ApplyFiberImageFilterMenu = uimenu(app.OptionsMenu);
            app.ApplyFiberImageFilterMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplyFiberImageFilterMenuSelected, true);
            app.ApplyFiberImageFilterMenu.Separator = 'on';
            app.ApplyFiberImageFilterMenu.Text = 'Apply Fiber Image Filter';
            app.SamplingCorrectionMenu = uimenu(app.OptionsMenu);
            app.SamplingCorrectionMenu.MenuSelectedFcn = createCallbackFcn(app, @SamplingCorrectionMenuSelected, true);
            app.SamplingCorrectionMenu.Text = 'Sampling Correction';
            app.SpectralSmoothingMenu = uimenu(app.OptionsMenu);
            app.SpectralSmoothingMenu.MenuSelectedFcn = createCallbackFcn(app, @SpectralSmoothingMenuSelected, true);
            app.SpectralSmoothingMenu.Text = 'Spectral Smoothing';
            app.DemoMenu = uimenu(app.UIFigure);
            app.DemoMenu.Text = 'Demo';
            app.hyperCFMEtestMenu = uimenu(app.DemoMenu);
            app.hyperCFMEtestMenu.MenuSelectedFcn = createCallbackFcn(app, @hyperCFMEtestMenuSelected, true);
            app.hyperCFMEtestMenu.Text = 'hyperCFME test';
            app.MaestrotestMenu = uimenu(app.DemoMenu);
            app.MaestrotestMenu.MenuSelectedFcn = createCallbackFcn(app, @MaestrotestMenuSelected, true);
            app.MaestrotestMenu.Text = 'Maestro test';
            app.ENVItestapplesMenu = uimenu(app.DemoMenu);
            app.ENVItestapplesMenu.MenuSelectedFcn = createCallbackFcn(app, @ENVItestapplesMenuSelected, true);
            app.ENVItestapplesMenu.Text = 'ENVI test - apples';
            app.ENVItestbloodstainsMenu = uimenu(app.DemoMenu);
            app.ENVItestbloodstainsMenu.MenuSelectedFcn = createCallbackFcn(app, @ENVItestbloodstainsMenuSelected, true);
            app.ENVItestbloodstainsMenu.Text = 'ENVI test - bloodstains';
            app.OlympustestMenu = uimenu(app.DemoMenu);
            app.OlympustestMenu.MenuSelectedFcn = createCallbackFcn(app, @OlympustestMenuSelected, true);
            app.OlympustestMenu.Text = 'Olympus test';
            app.OIRtiffstestMenu = uimenu(app.DemoMenu);
            app.OIRtiffstestMenu.MenuSelectedFcn = createCallbackFcn(app, @OIRtiffstestMenuSelected, true);
            app.OIRtiffstestMenu.Text = 'OIR-tiffs test';
            app.DebugcodeMenu = uimenu(app.DemoMenu);
            app.DebugcodeMenu.MenuSelectedFcn = createCallbackFcn(app, @DebugcodeMenuSelected, true);
            app.DebugcodeMenu.Text = 'Debug code';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hyperviewer2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end